classdef Putilov < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button           matlab.ui.control.Button
        Image            matlab.ui.control.Image
        Label            matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        Image2           matlab.ui.control.Image
        nEditFieldLabel  matlab.ui.control.Label
        nEditField       matlab.ui.control.NumericEditField
        Button_2         matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        EditField_2      matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            a=app.EditField.Value;
            img = imread(a);
            n = app.nEditField.Value;
            ch=app.EditField_2.Value;
            app.Image2.ImageSource = opp(img,n,ch);
           
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
           
            
        end

        % Callback function
        function vvesti1(app, event)
         
            
        end

        % Button pushed function: Button_2
        function otrit(app, event)
            [file,path] = uigetfile('*.jpg');
            file_name= strcat(path,file);
            app.Image.ImageSource=file_name;
            app.EditField.Value = file_name;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Начать работу';
            app.Image = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Открыт файл';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tag = 'gty';
            app.Image2 = uiimage(app.UIFigure);
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'Введите n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @otrit, true);
            app.Button_2.Text = 'Открыть';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Введите номер канала';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Putilov

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end