classdef credit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Open1Button          matlab.ui.control.Button
        Image                matlab.ui.control.Image
        startfunctionButton  matlab.ui.control.Button
        Image2               matlab.ui.control.Image
        SaveButton           matlab.ui.control.Button
        nLabel               matlab.ui.control.Label
        nEditField           matlab.ui.control.NumericEditField
        EditField2Label      matlab.ui.control.Label
        EditField2           matlab.ui.control.EditField
        TextArea             matlab.ui.control.TextArea
        TextArea_2           matlab.ui.control.TextArea
        Open2Button          matlab.ui.control.StateButton
        TextArea_3           matlab.ui.control.TextArea
        EditField3Label      matlab.ui.control.Label
        EditField3           matlab.ui.control.EditField
        Image3               matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Open1Button
        function Open1ButtonPushed(app, event)
             [file,path] = uigetfile('*.png','*.jpeg','*.jpg');
             file_name = strcat(path,file);
             app.Image.ImageSource = file_name; 
             app.EditField2.Value = file_name;
             
        end

        % Button pushed function: startfunctionButton
        function startfunctionButtonPushed(app, event)

                 fileName1 = app.EditField2.Value;
                 fileName2 = app.EditField3.Value;
                 img1 = imread(fileName1);
                 img2 = imread(fileName2);
                 
                 n = app.nEditField.Value;
                 
                 
                 app.Image2.ImageSource = processingFun(img1,img2,n);


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           
            img = app.Image2.ImageSource;
            imwrite(img, 'result.jpg')

        end

        % Value changed function: Open2Button
        function Open2ButtonValueChanged(app, event)
             [file,path] = uigetfile('*.png','*.jpeg','*.jpg');
             file_name = strcat(path,file);
             app.Image3.ImageSource = file_name; 
             app.EditField3.Value = file_name;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Open1Button = uibutton(app.UIFigure, 'push');
            app.Open1Button.ButtonPushedFcn = createCallbackFcn(app, @Open1ButtonPushed, true);
            app.Open1Button.Tag = 'OpenButton';
            app.Open1Button.Text = 'Open 1';
            app.Image = uiimage(app.UIFigure);
            app.Image.Tag = 'preview';
            app.startfunctionButton = uibutton(app.UIFigure, 'push');
            app.startfunctionButton.ButtonPushedFcn = createCallbackFcn(app, @startfunctionButtonPushed, true);
            app.startfunctionButton.Text = 'start function ';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Tag = 'result_image';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tag = 'SafeButton';
            app.SaveButton.Text = 'Save';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'Ведите n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Visible = 'off';
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField2.Visible = 'off';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Preview1'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Result Image'};
            app.Open2Button = uibutton(app.UIFigure, 'state');
            app.Open2Button.ValueChangedFcn = createCallbackFcn(app, @Open2ButtonValueChanged, true);
            app.Open2Button.Text = 'Open 2';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Preview2'};
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Visible = 'off';
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.EditField3.Visible = 'off';
            app.Image3 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = credit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end